classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Group4TourintothepictureUIFigure  matlab.ui.Figure
        controlPanel                    matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        Step1Label                      matlab.ui.control.Label
        Lamp_1                          matlab.ui.control.Lamp
        selectimageButton               matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        Step2Label                      matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        foregroundnumberEditFieldLabel  matlab.ui.control.Label
        foregroundnumberEditField       matlab.ui.control.NumericEditField
        segmentationButton              matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        Step3Label                      matlab.ui.control.Label
        Lamp_3                          matlab.ui.control.Lamp
        spiderymeshButton               matlab.ui.control.Button
        Panel_4                         matlab.ui.container.Panel
        Step4Label                      matlab.ui.control.Label
        Lamp_4                          matlab.ui.control.Lamp
        separatewallsButton             matlab.ui.control.Button
        imagepreviewPanel               matlab.ui.container.Panel
        preview                         matlab.ui.control.UIAxes
        StartTourPanel                  matlab.ui.container.Panel
        TourButton                      matlab.ui.control.Button
        CameraPanel                     matlab.ui.container.Panel
        XLabel                          matlab.ui.control.Label
        XSpinner                        matlab.ui.control.Spinner
        ZSpinnerLabel                   matlab.ui.control.Label
        ZSpinner                        matlab.ui.control.Spinner
        YLabel                          matlab.ui.control.Label
        YSpinner                        matlab.ui.control.Spinner
        saveimageButton                 matlab.ui.control.Button
        show3DButton                    matlab.ui.control.Button
        Slider                          matlab.ui.control.Slider
        InstructionPanel                matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        Panel_5                         matlab.ui.container.Panel
        resetButton                     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Lamp_1.Color = 'r';
            app.Lamp_2.Color = 'r';
            app.Lamp_3.Color = 'r'; 
            app.Lamp_4.Color = 'r'; 
            app.foregroundnumberEditField.Value = 1;
            app.XSpinner.Value = -100;
            app.YSpinner.Value = 0;
            app.ZSpinner.Value = 100;
            app.Slider.Value = 100;
            app.TextArea.Value = {('1. Execute step 1 to step 4.(Every step will have instructions here).');
                ('2. When step finished, the lamp will be green.');
                ('3. You can use camera panel to adjust the camera parameters.');
                ('4. After you determine camera parameters, you can get a new view of the image.');
                ('5. You can use the Start Tour panel to start a tour.')};
        end

        % Button pushed function: selectimageButton
        function selectimageButtonPushed(app, event)
            global Img
            [filename,pathname]=uigetfile({'*.png';'*.jpg';'*.*'},'choose picture');
            if isequal(filename,0) || isequal(pathname,0)
                errordlg("You have not choose any picture!","Error");
            else
                img_file = strcat(pathname,filename);
            end
            Img = imread(img_file);
            
            [sz1,sz2]=size(Img);
            % img size reduction, faster image processing
            if any(sz1>3000 | sz2>3000)            
                reduce_scal = 3200/max(sz1,sz2);            
                Img = imresize(Img, reduce_scal,"Antialiasing",false);            
            end
            
            cla(app.preview,'reset')
            axis(app.preview,'on')
            imshow(Img,'Parent',app.preview)
            app.Lamp_1.Color = 'g';
            app.TextArea.Value = {('Instructions for step 2');
                ('1. Please check how many foreground objects are in the image and enter them into foreground number.');
                ('2. Then you click segmentation button and circle all the foreground objects with four points respectively.');
                ('3. When you finish, double click to confirm.')};
            
            prompt = {'Enter rotate degree:'};
            dlgtitle = 'Input';
            definput = {'0'};
            dims = [1 35];
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            Img = imrotate(Img,str2double(answer{1}),'bilinear');
            cla(app.preview,'reset')
            imshow(Img,'Parent',app.preview)
        end

        % Button pushed function: segmentationButton
        function segmentationButtonPushed(app, event)
            global Img
            %% Image Segmentation
            % In this section, the foreground object is selected by the user using ROI.
            % The background picture is patched using inpaintExampler function. Outputs
            % are 2D coordinates of the foreground objects, foreground masks, and
            % background image.
            
            % backgound is a rgb image
            % foreground is a cell including n foreground objects 2D image
            % fg2D is a cell containing all the 2D coordinates of the corner points of
            % n foreground objects
            n = app.foregroundnumberEditField.Value;
            patchsize = 9;
            fillorder = 'gradient';
            [fg2D,foreground,background] = ImageSegment(Img,n,patchsize,fillorder);
            fg2D = cell2mat(fg2D);
            fg2D = reshape(fg2D,2,[]);
%             [fg2D_ir,foreground,background] = ImageSegment(Img,n,patchsize,fillorder);
%             fg2D = find_rectangular(fg2D_ir,n);
            
            assignin("base","foreground",foreground)
            assignin("base","background",background)
            assignin("base","fg2D",fg2D)
            cla(app.preview,'reset')
            imshow(background,'Parent',app.preview)
            app.Lamp_2.Color = 'g';
            app.TextArea.Value = {('Instructions for step 3');
                ('1. Adjust the position of the inner rectangle and the vanishing point in the image.');
                ('2. When you finish, you can close the figure.');
                };
        end

        % Button pushed function: spiderymeshButton
        function spiderymeshButtonPushed(app, event)
            %% Spidery mesh
            % This function is to create an interative tool to enable draggable inner
            % rectangle, vanning point and radial lines. The position of end point of
            % radial line will be updated while the inner rectangle or vanishing point
            % changed. The position of vanishing point will also be updated when radial
            % line is changing.
            background = evalin("base",'background');        
            [OutterPoint,Updated_VanishingPoint,Updated_InnerRectangle] = spidery_mesh(background);
            
            assignin("base","OutterPoint",OutterPoint)
            assignin("base","Updated_VanishingPoint",Updated_VanishingPoint)
            assignin("base","Updated_InnerRectangle",Updated_InnerRectangle)
            app.Lamp_3.Color = 'g';
            app.TextArea.Value = {('Instructions for step 4');
                ('1. Just click the button, preview will show you separate walls of background.');               
                };
        end

        % Button pushed function: separatewallsButton
        function separatewallsButtonPushed(app, event)
            global Img
            fg2D = evalin("base","fg2D");
            background = evalin("base",'background');
            OutterPoint = evalin("base","OutterPoint");
            Updated_VanishingPoint = evalin("base","Updated_VanishingPoint");
            Updated_InnerRectangle = evalin("base","Updated_InnerRectangle");
            
            %% Calculate 12 2D-Vertexes
            %  size(TwelfPoints) = (2,12)
            TwelfPoints_vp = gen12Points(Updated_VanishingPoint,Updated_InnerRectangle,OutterPoint);
            %% add black outline
            % To enable all the vertices visualized in the image, add black border for
            % original image
            % fill the missing parts that out of the view plane with black triangle
            [Img_pad,origin_image_pad,new_TwelfPoints_vp,new_fg2D] = get_image_pad(background,Img,TwelfPoints_vp,fg2D);
            
            cla(app.preview,'reset')
            imshow(Img_pad,'Parent',app.preview)
            hold(app.preview,'on')
            plot_2D_background(new_TwelfPoints_vp,Updated_InnerRectangle,app.preview)
        
            assignin("base","Img_pad",Img_pad)
            assignin("base","origin_image_pad",origin_image_pad)
            assignin("base","new_TwelfPoints_vp",new_TwelfPoints_vp)
            assignin("base","new_fg2D",new_fg2D)
            
            app.Lamp_4.Color = 'g';
            app.TextArea.Value = {('Instructions for camera adjustment');
                ('1. First you can click the show 3D button to see the 3D structure of the scene.When image size is big, it may take some time.');
                ('2. Then you can change the value of X,Y,Z(camera position) to adjust the view.');
                ('3. You can also use arrow keys on your keyboard to adjust the view, which is faster.');
                ('4. When you think the view is good, you can click save image button to save the current view as a JPG image named new_view.');
                };
        end

        % Button pushed function: show3DButton
        function show3DButtonPushed(app, event)
            global Img
            Img_pad = evalin("base","Img_pad");
            origin_image_pad = evalin("base","origin_image_pad");
            new_fg2D = evalin("base","new_fg2D");
            new_TwelfPoints_vp = evalin("base","new_TwelfPoints_vp");
            d = uiprogressdlg(app.Group4TourintothepictureUIFigure,'Title','Construct 3D room',...
        'Indeterminate','on');
    
            %% sperate 5 regions
            % generate mask for each region and then get individual image of each wall
            [leftwall, rearwall, rightwall, ceiling, floor] = image_matting(Img_pad, new_TwelfPoints_vp);
            
            %% perspective transform: get rectangles of 5 walls
            % In the view of trapeziform shape of each region, tilt correction should
            % be executed to obtain rectangle for each wall
            P = new_TwelfPoints_vp;
            outH = size(Img_pad,1);
            outW = size(Img_pad,2);
            if sum(sum(sum(leftwall)))==0

            else
                leftwall_rec = Perspective_transform(leftwall, P(:,11)', P(:,7)', P(:,5)', P(:,1)', outH, outW);
            end
            
            %subplot(3, 3, 5);
            rearwall_rec = Perspective_transform(rearwall, P(:,7)', P(:,8)', P(:,1)', P(:,2)', outH, outW);
            
            %subplot(3, 3, 6);
            if sum(sum(sum(rightwall)))==0
            
            else
                rightwall_rec = Perspective_transform(rightwall, P(:,8)', P(:,12)', P(:,2)', P(:,6)', outH, outW);
            end
            
            %subplot(3, 3, 2);
            if sum(sum(sum(ceiling)))==0
            
            else
                ceiling_rec = Perspective_transform(ceiling, P(:,9)', P(:,10)', P(:,7)', P(:,8)', outH, outW);
            end
            
            %subplot(3, 3, 8);
            floor_rec = Perspective_transform(floor, P(:,1)', P(:,2)', P(:,3)', P(:,4)', outH, outW);
            
            %% 3D box construction
            % In this section, the 3D coordinates of the twelf points and the vanishing
            % point is generated using reverse perspective projection. The depth
            % value of the points is magnified by a constant ration k.
            k = 0.55 * size(Img,1);
            [TwelfPoints_3D,VanishingPoint_3D] = boxconstruction(new_TwelfPoints_vp,k);
            TwelfPoints_3D_xdirection_change = [TwelfPoints_3D(:,2),TwelfPoints_3D(:,1),TwelfPoints_3D(:,4),TwelfPoints_3D(:,3),TwelfPoints_3D(:,6),...
TwelfPoints_3D(:,5),TwelfPoints_3D(:,8),TwelfPoints_3D(:,7),TwelfPoints_3D(:,10),TwelfPoints_3D(:,9),TwelfPoints_3D(:,12),TwelfPoints_3D(:,11)];
            
            %% foregroundobject 3D parameters calculation
            % this function is used to get the 3D parameters of the foreground objects
            % fg3D size(3,4*n)
            % fg_polygon_function 1*n system,represents the foregroundobjects 3D planes
            % fg_image 1*n cell,save the rgb matrix of each foregroundobject textures
            n = app.foregroundnumberEditField.Value;
            patchsize = 9;
            fillorder = 'gradient';
            [fg3D, fg_polygon_function,fg_image] = fg2Dto3D(n,origin_image_pad,new_TwelfPoints_vp,TwelfPoints_3D_xdirection_change,new_fg2D,patchsize,fillorder);
            
            %% construct 3D room
            % Take 3D coordinates of 12 vertices and foreground object as input, the surface of
            % each wall and foreground object polygon can be plotted in 3D model of room
            if sum(sum(sum(leftwall)))==0 || sum(sum(sum(rightwall)))==0
                [f,ax] = construct_3D_room(TwelfPoints_3D,fg3D,n,fg_polygon_function,fg_image,rearwall_rec,floor_rec);
            else
                [f,ax] = construct_3D_room(TwelfPoints_3D,fg3D,n,fg_polygon_function,fg_image,leftwall_rec,rearwall_rec,rightwall_rec,ceiling_rec,floor_rec);
            end
            
            center = [(TwelfPoints_3D(1,1)+TwelfPoints_3D(1,2))/2,(TwelfPoints_3D(2,1)+TwelfPoints_3D(2,7))/2];
            app.XSpinner.Value = center(1);
            app.YSpinner.Value = center(2);
            app.ZSpinner.Value = VanishingPoint_3D(3)*0.1;
            app.Slider.Value = app.ZSpinner.Value;
            cam_position = [app.XSpinner.Value,app.YSpinner.Value,app.ZSpinner.Value];
            close(d)
            show3Droom(ax,cam_position,VanishingPoint_3D)
            
            assignin("base",'ax',ax)
            assignin("base",'f',f)
            assignin("base",'VanishingPoint_3D',VanishingPoint_3D)
            assignin("base",'TwelfPoints_3D',TwelfPoints_3D)
        end

        % Button pushed function: saveimageButton
        function saveimageButtonPushed(app, event)
            f = evalin("base",'f');
            saveas(f,'new_view.jpg')
            app.TextArea.Value = {('Instructions for Start Tour');
                ('1. Just click the button, you will start the tour into the picture');               
                };
        end

        % Button pushed function: TourButton
        function TourButtonPushed(app, event)
            ax = evalin("base",'ax');
            VanishingPoint_3D = evalin('base','VanishingPoint_3D');
            TwelfPoints_3D = evalin("base",'TwelfPoints_3D');
            start_animation(ax,VanishingPoint_3D,TwelfPoints_3D)
        end

        % Key press function: Group4TourintothepictureUIFigure
        function Group4TourintothepictureUIFigureKeyPress(app, event)
            key = event.Key;
            ax = evalin("base",'ax');
            camera_position = keypress(ax,key);
            app.XSpinner.Value = camera_position(1);
            app.YSpinner.Value = camera_position(2);
            app.ZSpinner.Value = camera_position(3);
        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            value = app.XSpinner.Value;
            ax = evalin("base",'ax');
            VanishingPoint_3D = evalin('base','VanishingPoint_3D');
            %TwelfPoints_3D = evalin("base",'TwelfPoints_3D');
            cam_position = [value,app.YSpinner.Value,app.ZSpinner.Value];
            show3Droom(ax,cam_position,VanishingPoint_3D)
        end

        % Value changed function: ZSpinner
        function ZSpinnerValueChanged(app, event)
            value = app.ZSpinner.Value;
            ax = evalin("base",'ax');
            VanishingPoint_3D = evalin('base','VanishingPoint_3D');
            %TwelfPoints_3D = evalin("base",'TwelfPoints_3D');
            cam_position = [app.XSpinner.Value,app.YSpinner.Value,value];
            show3Droom(ax,cam_position,VanishingPoint_3D)
        end

        % Value changed function: YSpinner
        function YSpinnerValueChanged(app, event)
            value = app.YSpinner.Value;
            ax = evalin("base",'ax');
            VanishingPoint_3D = evalin('base','VanishingPoint_3D');
            %TwelfPoints_3D = evalin("base",'TwelfPoints_3D');
            cam_position = [app.XSpinner.Value,value,app.ZSpinner.Value];
            show3Droom(ax,cam_position,VanishingPoint_3D)
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            f = evalin("base",'f');
            close(f)
            cla(app.preview,'reset')
            startupFcn(app)
            app.preview.Visible = false;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            ax = evalin("base",'ax');
            VanishingPoint_3D = evalin('base','VanishingPoint_3D');
            app.ZSpinner.Value = value;
            cam_position = [app.XSpinner.Value,app.YSpinner.Value,value];
            show3Droom(ax,cam_position,VanishingPoint_3D)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Group4TourintothepictureUIFigure = uifigure('Visible', 'off');
            app.Group4TourintothepictureUIFigure.Name = 'Group 4: Tour into the picture';
            app.Group4TourintothepictureUIFigure.KeyPressFcn = createCallbackFcn(app, @Group4TourintothepictureUIFigureKeyPress, true);
            app.controlPanel = uipanel(app.Group4TourintothepictureUIFigure);
            app.controlPanel.Title = 'control';
            app.Panel = uipanel(app.controlPanel);
            app.Step1Label = uilabel(app.Panel);
            app.Step1Label.Text = 'Step 1';
            app.Lamp_1 = uilamp(app.Panel);
            app.selectimageButton = uibutton(app.Panel, 'push');
            app.selectimageButton.ButtonPushedFcn = createCallbackFcn(app, @selectimageButtonPushed, true);
            app.selectimageButton.Icon = 'image.png';
            app.selectimageButton.Text = 'select image';
            app.Panel_2 = uipanel(app.controlPanel);
            app.Step2Label = uilabel(app.Panel_2);
            app.Step2Label.Text = 'Step 2';
            app.Lamp_2 = uilamp(app.Panel_2);
            app.foregroundnumberEditFieldLabel = uilabel(app.Panel_2);
            app.foregroundnumberEditFieldLabel.Text = 'foreground number:';
            app.foregroundnumberEditField = uieditfield(app.Panel_2, 'numeric');
            app.segmentationButton = uibutton(app.Panel_2, 'push');
            app.segmentationButton.ButtonPushedFcn = createCallbackFcn(app, @segmentationButtonPushed, true);
            app.segmentationButton.Icon = 'segmentation.png';
            app.segmentationButton.Text = 'segmentation';
            app.Panel_3 = uipanel(app.controlPanel);
            app.Step3Label = uilabel(app.Panel_3);
            app.Step3Label.Text = 'Step 3';
            app.Lamp_3 = uilamp(app.Panel_3);
            app.spiderymeshButton = uibutton(app.Panel_3, 'push');
            app.spiderymeshButton.ButtonPushedFcn = createCallbackFcn(app, @spiderymeshButtonPushed, true);
            app.spiderymeshButton.Icon = 'spidery.png';
            app.spiderymeshButton.Text = 'spidery mesh';
            app.Panel_4 = uipanel(app.controlPanel);
            app.Step4Label = uilabel(app.Panel_4);
            app.Step4Label.Text = 'Step 4';
            app.Lamp_4 = uilamp(app.Panel_4);
            app.separatewallsButton = uibutton(app.Panel_4, 'push');
            app.separatewallsButton.ButtonPushedFcn = createCallbackFcn(app, @separatewallsButtonPushed, true);
            app.separatewallsButton.Icon = 'wall.png';
            app.separatewallsButton.Text = 'separate walls';
            app.imagepreviewPanel = uipanel(app.Group4TourintothepictureUIFigure);
            app.imagepreviewPanel.Title = 'image preview';
            app.preview = uiaxes(app.imagepreviewPanel);
            title(app.preview, '')
            xlabel(app.preview, '')
            ylabel(app.preview, '')
            app.preview.Visible = 'off';
            app.StartTourPanel = uipanel(app.Group4TourintothepictureUIFigure);
            app.StartTourPanel.Title = 'Start Tour';
            app.TourButton = uibutton(app.StartTourPanel, 'push');
            app.TourButton.ButtonPushedFcn = createCallbackFcn(app, @TourButtonPushed, true);
            app.TourButton.Icon = 'video-camera-solid.png';
            app.TourButton.Text = 'Tour';
            app.CameraPanel = uipanel(app.Group4TourintothepictureUIFigure);
            app.CameraPanel.Title = 'Camera';
            app.XLabel = uilabel(app.CameraPanel);
            app.XLabel.Text = 'X:';
            app.XSpinner = uispinner(app.CameraPanel);
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.ZSpinnerLabel = uilabel(app.CameraPanel);
            app.ZSpinnerLabel.Text = 'Z:';
            app.ZSpinner = uispinner(app.CameraPanel);
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSpinnerValueChanged, true);
            app.YLabel = uilabel(app.CameraPanel);
            app.YLabel.Text = 'Y:';
            app.YSpinner = uispinner(app.CameraPanel);
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @YSpinnerValueChanged, true);
            app.saveimageButton = uibutton(app.CameraPanel, 'push');
            app.saveimageButton.ButtonPushedFcn = createCallbackFcn(app, @saveimageButtonPushed, true);
            app.saveimageButton.Icon = 'save.png';
            app.saveimageButton.Text = 'save image';
            app.show3DButton = uibutton(app.CameraPanel, 'push');
            app.show3DButton.ButtonPushedFcn = createCallbackFcn(app, @show3DButtonPushed, true);
            app.show3DButton.Icon = 'threeD.png';
            app.show3DButton.Text = 'show 3D';
            app.Slider = uislider(app.CameraPanel);
            app.Slider.Limits = [-100 500];
            app.Slider.MajorTicks = [-100 500];
            app.Slider.MajorTickLabels = {'-100', '500'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.InstructionPanel = uipanel(app.Group4TourintothepictureUIFigure);
            app.InstructionPanel.Title = 'Instruction';
            app.TextArea = uitextarea(app.InstructionPanel);
            app.TextArea.Value = {'1. Execute step 1 to step 4.(step2,3 will have instructions here)'; '2. You can use camera panel to adjust the camera parameters.'; '3. After you determine camera parameters, you can get a new view of the image.'; '4. You can use the Start Tour panel to start a tour.'};
            app.Panel_5 = uipanel(app.Group4TourintothepictureUIFigure);
            app.resetButton = uibutton(app.Panel_5, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Icon = 'reset.png';
            app.resetButton.Text = 'reset';
            % Show the figure after all components are created
            app.Group4TourintothepictureUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Group4TourintothepictureUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Group4TourintothepictureUIFigure)
        end
    end
end