classdef Tour_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Group4TourintothepictureUIFigure  matlab.ui.Figure
        controlPanel                    matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        selectimageButton               matlab.ui.control.Button
        Step1Label                      matlab.ui.control.Label
        Lamp_1                          matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        Step2Label                      matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        Label_2                         matlab.ui.control.Label
        foregroundnumberEditFieldLabel  matlab.ui.control.Label
        foregroundnumberEditField       matlab.ui.control.NumericEditField
        startButton                     matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        spiderymeshButton               matlab.ui.control.Button
        Step3Label                      matlab.ui.control.Label
        Lamp_3                          matlab.ui.control.Lamp
        Label_3                         matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        dConstrcutionButton             matlab.ui.control.Button
        Step4Label                      matlab.ui.control.Label
        Lamp_4                          matlab.ui.control.Lamp
        Label_4                         matlab.ui.control.Label
        imagePanel                      matlab.ui.container.Panel
        preview                         matlab.ui.control.UIAxes
        StartToutPanel                  matlab.ui.container.Panel
        TourButton                      matlab.ui.control.Button
        cameraPanel                     matlab.ui.container.Panel
        XLabel                          matlab.ui.control.Label
        XSpinner                        matlab.ui.control.Spinner
        ZSpinnerLabel                   matlab.ui.control.Label
        ZSpinner                        matlab.ui.control.Spinner
        YLabel                          matlab.ui.control.Label
        YSpinner                        matlab.ui.control.Spinner
        saveimageButton                 matlab.ui.control.Button
        show3DButton                    matlab.ui.control.Button
        instructionPanel                matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Lamp_1.Color = 'r';
            app.Lamp_2.Color = 'r';
            app.Lamp_3.Color = 'r'; 
            app.Lamp_4.Color = 'r'; 
            app.foregroundnumberEditField.Value = 1;
            app.XSpinner.Value = -100;
            app.YSpinner.Value = 0;
            app.ZSpinner.Value = 100;
            app.TextArea.Value = {('1. Execute step 1 to step 4.(Every step will have instructions here)');
                ('2. You can use camera panel to adjust the camera parameters.');
                ('3. After you determine camera parameters, you can get a new view of the image.');
                ('4. You can use the Start Tour panel to start a tour.')};
        end

        % Button pushed function: selectimageButton
        function selectimageButtonPushed(app, event)
            [filename,pathname]=uigetfile({'*.png';'*.jpg';'*.*'},'choose picture');
            if isequal(filename,0) || isequal(pathname,0)
                errordlg("You have not choose any picture!","Error");
            else
                img_file = strcat(pathname,filename);
            end
            global Img
            Img = imread(img_file);
            axis(app.preview,'on')
            imshow(Img,'Parent',app.preview)
            app.Lamp_1.Color = 'g';
            app.TextArea.Value = {('Instructions for step 2');
                ('1. Please check how many foreground objects are in the image and enter them into ');
                ('2. Then you click start button and circle all the foreground objects with four grid dots respectively');
                ('3. When you finish, double click to confirm.')};
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            global Img 
            [sz1,sz2]=size(Img);
            % img size reduction
            if any(sz1>3000 | sz2>3000)            
                reduce_scal = 3200/max(sz1,sz2);            
                Img = imresize(Img, reduce_scal,"Antialiasing",false);            
            end
            n = app.foregroundnumberEditField.Value;
            patchsize = 9;
            fillorder = 'gradient';
            [fg2D_ir,foreground,background] = ImageSegment(Img,n,patchsize,fillorder);
            fg2D = find_rectangular(fg2D_ir,n);
            assignin("base","foreground",foreground)
            assignin("base","background",background)
            assignin("base","fg2D",fg2D)
            imshow(background,'Parent',app.preview)
            app.Lamp_2.Color = 'g';
            app.TextArea.Value = {('Instructions for step 3');
                ('1. Adjust the position of the inner rectangle and the vanishing point in the image');
                ('2. When you finish, you can close the figure');
                };
        end

        % Button pushed function: spiderymeshButton
        function spiderymeshButtonPushed(app, event)
            background = evalin("base",'background');        
            [OutterPoint,Updated_VanishingPoint,Updated_InnerRectangle] = spidery_mesh(background);
            assignin("base","OutterPoint",OutterPoint)
            assignin("base","Updated_VanishingPoint",Updated_VanishingPoint)
            assignin("base","Updated_InnerRectangle",Updated_InnerRectangle)
            app.Lamp_3.Color = 'g';
            app.TextArea.Value = {('Instructions for step 4');
                ('1. Just click the button, preview will show you seperate walls of background');               
                };
        end

        % Button pushed function: show3DButton
        function show3DButtonPushed(app, event)
            global Img
            Img_pad = evalin("base","Img_pad");
            origin_image_pad = evalin("base","origin_image_pad");
            new_fg2D = evalin("base","new_fg2D");
            new_TwelfPoints_vp = evalin("base","new_TwelfPoints_vp");
            [leftwall, rearwall, rightwall, ceiling, floor] = image_matting(Img_pad, new_TwelfPoints_vp);
            [leftwall_rec,rearwall_rec,rightwall_rec,ceiling_rec,floor_rec] = get_5wall_rect(new_TwelfPoints_vp,Img_pad,leftwall,rearwall,rightwall,ceiling,floor);
            k = 0.55 * size(Img,1);
            [TwelfPoints_3D,VanishingPoint_3D] = boxconstruction(new_TwelfPoints_vp,k);
            TwelfPoints_3D_xdirection_change = [TwelfPoints_3D(:,2),TwelfPoints_3D(:,1),TwelfPoints_3D(:,4),TwelfPoints_3D(:,3),TwelfPoints_3D(:,6),...
TwelfPoints_3D(:,5),TwelfPoints_3D(:,8),TwelfPoints_3D(:,7),TwelfPoints_3D(:,10),TwelfPoints_3D(:,9),TwelfPoints_3D(:,12),TwelfPoints_3D(:,11)];
            
            n = app.foregroundnumberEditField.Value;
            [fg3D, fg_polygon_function] = fg2Dto3D(n,origin_image_pad,new_TwelfPoints_vp,TwelfPoints_3D_xdirection_change,new_fg2D);
            if sum(sum(sum(leftwall)))==0 || sum(sum(sum(rightwall)))==0
             [f,ax] = construct_3D_room(TwelfPoints_3D,fg3D,n,fg_polygon_function,rearwall_rec,floor_rec);
            else
             [f,ax] = construct_3D_room(TwelfPoints_3D,fg3D,n,fg_polygon_function,leftwall_rec,rearwall_rec,rightwall_rec,ceiling_rec,floor_rec);
            end
            cam_position = [app.XSpinner.Value,app.YSpinner.Value,app.ZSpinner.Value];
            show3Droom(ax,cam_position,VanishingPoint_3D,TwelfPoints_3D)
            assignin("base",'ax',ax)
            assignin("base",'f',f)
            assignin("base",'VanishingPoint_3D',VanishingPoint_3D)
            assignin("base",'TwelfPoints_3D',TwelfPoints_3D)
        end

        % Button pushed function: dConstrcutionButton
        function dConstrcutionButtonPushed(app, event)
            global Img
            fg2D = evalin("base","fg2D");
            background = evalin("base",'background');
            OutterPoint = evalin("base","OutterPoint");
            Updated_VanishingPoint = evalin("base","Updated_VanishingPoint");
            Updated_InnerRectangle = evalin("base","Updated_InnerRectangle");
            TwelfPoints_vp = gen12Points(Updated_VanishingPoint,Updated_InnerRectangle,OutterPoint);
            [Img_pad,origin_image_pad,new_TwelfPoints_vp,new_fg2D] = get_image_pad(background,Img,TwelfPoints_vp,fg2D);
            imshow(Img_pad,'Parent',app.preview)
            hold(app.preview,'on')
            plot_2D_background(new_TwelfPoints_vp,Updated_InnerRectangle,app.preview)
        
            assignin("base","Img_pad",Img_pad)
            assignin("base","origin_image_pad",origin_image_pad)
            assignin("base","new_TwelfPoints_vp",new_TwelfPoints_vp)
            assignin("base","new_fg2D",new_fg2D)
            
            app.Lamp_4.Color = 'g';
            app.TextArea.Value = {('Instructions for camera adjustment');
                ('1. First you can click the show 3D button to see the 3D structure of the scene.');
                ('2. Then you can change the value of X,Y,Z(camera position) to adjust the view');
                ('3. You can also use arrow keys on your keyboard to adjust the view, which is faster');
                ('4. When you think the view is good, you can click save image botton to save the current view as a JPG image');
                };
        end

        % Button pushed function: saveimageButton
        function saveimageButtonPushed(app, event)
            f = evalin("base",'f');
            saveas(f,'new_view.jpg')
            app.TextArea.Value = {('Instructions for Start Tour');
                ('1. Just click the button, you will start the tour into the picture');               
                };
        end

        % Button pushed function: TourButton
        function TourButtonPushed(app, event)
            ax = evalin("base",'ax');
            VanishingPoint_3D = evalin('base','VanishingPoint_3D');
            start_animation(ax,VanishingPoint_3D)
        end

        % Key press function: Group4TourintothepictureUIFigure
        function Group4TourintothepictureUIFigureKeyPress(app, event)
            key = event.Key;
            ax = evalin("base",'ax');
            keypress(ax,key)
        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            value = app.XSpinner.Value;
            ax = evalin("base",'ax');
            VanishingPoint_3D = evalin('base','VanishingPoint_3D');
            TwelfPoints_3D = evalin("base",'TwelfPoints_3D');
            cam_position = [value,app.YSpinner.Value,app.ZSpinner.Value];
            show3Droom(ax,cam_position,VanishingPoint_3D,TwelfPoints_3D)
        end

        % Value changed function: ZSpinner
        function ZSpinnerValueChanged(app, event)
            value = app.ZSpinner.Value;
            ax = evalin("base",'ax');
            VanishingPoint_3D = evalin('base','VanishingPoint_3D');
            TwelfPoints_3D = evalin("base",'TwelfPoints_3D');
            cam_position = [app.XSpinner.Value,app.YSpinner.Value,value];
            show3Droom(ax,cam_position,VanishingPoint_3D,TwelfPoints_3D)
        end

        % Value changed function: YSpinner
        function YSpinnerValueChanged(app, event)
            value = app.YSpinner.Value;
            ax = evalin("base",'ax');
            VanishingPoint_3D = evalin('base','VanishingPoint_3D');
            TwelfPoints_3D = evalin("base",'TwelfPoints_3D');
            cam_position = [app.XSpinner.Value,value,app.ZSpinner.Value];
            show3Droom(ax,cam_position,VanishingPoint_3D,TwelfPoints_3D)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Group4TourintothepictureUIFigure = uifigure('Visible', 'off');
            app.Group4TourintothepictureUIFigure.Name = 'Group 4: Tour into the picture';
            app.Group4TourintothepictureUIFigure.KeyPressFcn = createCallbackFcn(app, @Group4TourintothepictureUIFigureKeyPress, true);
            app.controlPanel = uipanel(app.Group4TourintothepictureUIFigure);
            app.controlPanel.Title = 'control';
            app.Panel = uipanel(app.controlPanel);
            app.selectimageButton = uibutton(app.Panel, 'push');
            app.selectimageButton.ButtonPushedFcn = createCallbackFcn(app, @selectimageButtonPushed, true);
            app.selectimageButton.Text = 'select image';
            app.Step1Label = uilabel(app.Panel);
            app.Step1Label.Text = 'Step 1';
            app.Lamp_1 = uilamp(app.Panel);
            app.Label = uilabel(app.Panel);
            app.Label.Text = '———————————';
            app.Panel_2 = uipanel(app.controlPanel);
            app.Step2Label = uilabel(app.Panel_2);
            app.Step2Label.Text = 'Step 2';
            app.Lamp_2 = uilamp(app.Panel_2);
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '———————————';
            app.foregroundnumberEditFieldLabel = uilabel(app.Panel_2);
            app.foregroundnumberEditFieldLabel.Text = 'foreground number:';
            app.foregroundnumberEditField = uieditfield(app.Panel_2, 'numeric');
            app.startButton = uibutton(app.Panel_2, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.Panel_3 = uipanel(app.controlPanel);
            app.spiderymeshButton = uibutton(app.Panel_3, 'push');
            app.spiderymeshButton.ButtonPushedFcn = createCallbackFcn(app, @spiderymeshButtonPushed, true);
            app.spiderymeshButton.Text = 'spidery mesh';
            app.Step3Label = uilabel(app.Panel_3);
            app.Step3Label.Text = 'Step 3';
            app.Lamp_3 = uilamp(app.Panel_3);
            app.Label_3 = uilabel(app.Panel_3);
            app.Label_3.Text = '———————————';
            app.Panel_4 = uipanel(app.controlPanel);
            app.dConstrcutionButton = uibutton(app.Panel_4, 'push');
            app.dConstrcutionButton.ButtonPushedFcn = createCallbackFcn(app, @dConstrcutionButtonPushed, true);
            app.dConstrcutionButton.Text = '3d Constrcution';
            app.Step4Label = uilabel(app.Panel_4);
            app.Step4Label.Text = 'Step 4';
            app.Lamp_4 = uilamp(app.Panel_4);
            app.Label_4 = uilabel(app.Panel_4);
            app.Label_4.Text = '———————————';
            app.imagePanel = uipanel(app.Group4TourintothepictureUIFigure);
            app.imagePanel.Title = 'image';
            app.preview = uiaxes(app.imagePanel);
            title(app.preview, 'preview')
            xlabel(app.preview, '')
            ylabel(app.preview, '')
            app.StartToutPanel = uipanel(app.Group4TourintothepictureUIFigure);
            app.StartToutPanel.Title = 'Start Tout';
            app.TourButton = uibutton(app.StartToutPanel, 'push');
            app.TourButton.ButtonPushedFcn = createCallbackFcn(app, @TourButtonPushed, true);
            app.TourButton.Text = 'Tour';
            app.cameraPanel = uipanel(app.Group4TourintothepictureUIFigure);
            app.cameraPanel.Title = 'camera';
            app.XLabel = uilabel(app.cameraPanel);
            app.XLabel.Text = 'X:';
            app.XSpinner = uispinner(app.cameraPanel);
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.ZSpinnerLabel = uilabel(app.cameraPanel);
            app.ZSpinnerLabel.Text = 'Z:';
            app.ZSpinner = uispinner(app.cameraPanel);
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSpinnerValueChanged, true);
            app.YLabel = uilabel(app.cameraPanel);
            app.YLabel.Text = 'Y:';
            app.YSpinner = uispinner(app.cameraPanel);
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @YSpinnerValueChanged, true);
            app.saveimageButton = uibutton(app.cameraPanel, 'push');
            app.saveimageButton.ButtonPushedFcn = createCallbackFcn(app, @saveimageButtonPushed, true);
            app.saveimageButton.Text = 'save image';
            app.show3DButton = uibutton(app.cameraPanel, 'push');
            app.show3DButton.ButtonPushedFcn = createCallbackFcn(app, @show3DButtonPushed, true);
            app.show3DButton.Text = 'show 3D';
            app.instructionPanel = uipanel(app.Group4TourintothepictureUIFigure);
            app.instructionPanel.Title = 'instruction';
            app.TextArea = uitextarea(app.instructionPanel);
            app.TextArea.Value = {'1. Execute step 1 to step 4.(step2,3 will have instructions here)'; '2. You can use camera panel to adjust the camera parameters.'; '3. After you determine camera parameters, you can get a new view of the image.'; '4. You can use the Start Tour panel to start a tour.'};
            % Show the figure after all components are created
            app.Group4TourintothepictureUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tour_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Group4TourintothepictureUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Group4TourintothepictureUIFigure)
        end
    end
end