classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        imagePanel                    matlab.ui.container.Panel
        original                      matlab.ui.control.UIAxes
        preview                       matlab.ui.control.UIAxes
        controlpanelPanel             matlab.ui.container.Panel
        selectimageButton             matlab.ui.control.Button
        exitprogramButton             matlab.ui.control.Button
        instructionButton             matlab.ui.control.Button
        backgroundisolatePanel        matlab.ui.container.Panel
        startButton                   matlab.ui.control.Button
        foregroundnumberLabel         matlab.ui.control.Label
        foregroundnumberEditField     matlab.ui.control.NumericEditField
        background_lamp               matlab.ui.control.Lamp
        spidermeshPanel               matlab.ui.container.Panel
        startButton_2                 matlab.ui.control.Button
        vanishingpointEditFieldLabel  matlab.ui.control.Label
        vanishingpointEditField       matlab.ui.control.NumericEditField
        vanishingpointEditField_2     matlab.ui.control.NumericEditField
        spider_mesh_lamp              matlab.ui.control.Lamp
        cameraPanel                   matlab.ui.container.Panel
        distanceLabel                 matlab.ui.control.Label
        distanceSlider                matlab.ui.control.Slider
        currentviewLabel              matlab.ui.control.Label
        currentviewEditField_x        matlab.ui.control.NumericEditField
        adjustcameraButton            matlab.ui.control.Button
        currentviewEditField_z        matlab.ui.control.NumericEditField
        starttourPanel                matlab.ui.container.Panel
        saveButton                    matlab.ui.control.Button
        confirmButton                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.background_lamp.Color = 'red';
            app.spider_mesh_lamp.Color = 'red';
            app.foregroundnumberEditField.Value = 1;
        end

        % Button pushed function: selectimageButton
        function selectimageButtonPushed(app, event)
            [filename,pathname]=uigetfile({'*.png';'*.jpg';'*.*'},'choose picture');
            if isequal(filename,0) || isequal(pathname,0)
                errordlg("You have not choose any picture!","Error");
            else
                img_file = strcat(pathname,filename);
            end
            global img
            img = imread(img_file);
            axis(app.original,'on')
            %axis(app.img1,'off')
            imshow(img,'Parent',app.original)
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            global img 
            uialert(app.UIFigure,'Please do not close the figure until lamp change to green, otherwise the process will fail.' ...
                ,'Info','icon',"info")
            pause(3);
            n = app.foregroundnumberEditField.Value;
            patchsize = 18;
            fillorder = 'gradient';
            [foreground,background] = ImageSegment(img,n,patchsize,fillorder,app.UIFigure);
            imshow(background,'Parent',app.preview)
            app.background_lamp.Color = 'g';
            %imshow(foreground,'Parent',app.preview)
        end

        % Button pushed function: startButton_2
        function startButton_2Pushed(app, event)
            global img
            uialert(app.UIFigure,'Please do not close the figure until lamp change to green, otherwise the process will fail.' ...
                ,'Info','icon',"info")
            pause(3);
            [l1,l2,vanishing_point,inner_rectangle] = spidery_mesh(img);
            app.vanishingpointEditField.Value = round(vanishing_point.Source{1}.Position(1));
            app.vanishingpointEditField_2.Value = round(vanishing_point.Source{1}.Position(2));
            %spidery_mesh(img);
            app.spider_mesh_lamp.Color = 'g';
        end

        % Button pushed function: adjustcameraButton
        function adjustcameraButtonPushed(app, event)
            imshow('metro-station.png')
            h = rotate3d;
            h.ActionPostCallback = @mypostcallback;
            h.Enable = 'on';
            function mypostcallback(~,evd)
                newView = round(evd.Axes.View);              
                app.currentviewEditField_x.Value = newView(1);
                app.currentviewEditField_z.Value = newView(2);
                %msgbox(sprintf('The new view is [%d %d].',newView));
            end
        end

        % Button pushed function: exitprogramButton
        function exitprogramButtonPushed(app, event)
            close(app.UIFigure)
            close all
        end

        % Button pushed function: instructionButton
        function instructionButtonPushed(app, event)
            uialert(app.UIFigure,"1. You can use select image button to select a image that you want to precess. " +...
                "2. Then you use background isolate panel to select foreground and creat isolate background. " + ...
                "3. After that, you can use spider mesh panel to change the position of vanishing point and the size of inner rectangle. " + ...
                "4. Finally, you can use camera panel to change camera parameters. If you don't change, default parameters will be used. " + ...
                "5. You can click corfirm button on start tour panel to see the processed image and use save button to save it." ...
                ,'Info','icon',"info")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Tour into the picture';
            app.imagePanel = uipanel(app.UIFigure);
            app.imagePanel.Title = 'image';
            app.original = uiaxes(app.imagePanel);
            title(app.original, 'original')
            xlabel(app.original, '')
            ylabel(app.original, '')
            app.preview = uiaxes(app.imagePanel);
            title(app.preview, 'preview')
            xlabel(app.preview, '')
            ylabel(app.preview, '')
            app.controlpanelPanel = uipanel(app.UIFigure);
            app.controlpanelPanel.Title = 'control panel';
            app.selectimageButton = uibutton(app.controlpanelPanel, 'push');
            app.selectimageButton.ButtonPushedFcn = createCallbackFcn(app, @selectimageButtonPushed, true);
            app.selectimageButton.Text = 'select image';
            app.exitprogramButton = uibutton(app.controlpanelPanel, 'push');
            app.exitprogramButton.ButtonPushedFcn = createCallbackFcn(app, @exitprogramButtonPushed, true);
            app.exitprogramButton.Text = 'exit program';
            app.instructionButton = uibutton(app.controlpanelPanel, 'push');
            app.instructionButton.ButtonPushedFcn = createCallbackFcn(app, @instructionButtonPushed, true);
            app.instructionButton.Text = 'instruction';
            app.backgroundisolatePanel = uipanel(app.UIFigure);
            app.backgroundisolatePanel.Title = 'background isolate';
            app.startButton = uibutton(app.backgroundisolatePanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Tooltip = {'Please do not close the figure until lamp change to green, otherwise the process will fail.'};
            app.startButton.Text = 'start';
            app.foregroundnumberLabel = uilabel(app.backgroundisolatePanel);
            app.foregroundnumberLabel.Text = 'foreground number:';
            app.foregroundnumberEditField = uieditfield(app.backgroundisolatePanel, 'numeric');
            app.background_lamp = uilamp(app.backgroundisolatePanel);
            app.background_lamp.Tooltip = {''};
            app.spidermeshPanel = uipanel(app.UIFigure);
            app.spidermeshPanel.Title = 'spider mesh';
            app.startButton_2 = uibutton(app.spidermeshPanel, 'push');
            app.startButton_2.ButtonPushedFcn = createCallbackFcn(app, @startButton_2Pushed, true);
            app.startButton_2.Tooltip = {'Please do not close the figure until lamp change to green, otherwise the process will fail.'};
            app.startButton_2.Text = 'start';
            app.vanishingpointEditFieldLabel = uilabel(app.spidermeshPanel);
            app.vanishingpointEditFieldLabel.Text = 'vanishing point:';
            app.vanishingpointEditField = uieditfield(app.spidermeshPanel, 'numeric');
            app.vanishingpointEditField_2 = uieditfield(app.spidermeshPanel, 'numeric');
            app.spider_mesh_lamp = uilamp(app.spidermeshPanel);
            app.cameraPanel = uipanel(app.UIFigure);
            app.cameraPanel.Title = 'camera';
            app.distanceLabel = uilabel(app.cameraPanel);
            app.distanceLabel.Text = 'distance:';
            app.distanceSlider = uislider(app.cameraPanel);
            app.currentviewLabel = uilabel(app.cameraPanel);
            app.currentviewLabel.Text = 'current view:';
            app.currentviewEditField_x = uieditfield(app.cameraPanel, 'numeric');
            app.adjustcameraButton = uibutton(app.cameraPanel, 'push');
            app.adjustcameraButton.ButtonPushedFcn = createCallbackFcn(app, @adjustcameraButtonPushed, true);
            app.adjustcameraButton.Text = 'adjust camera';
            app.currentviewEditField_z = uieditfield(app.cameraPanel, 'numeric');
            app.starttourPanel = uipanel(app.UIFigure);
            app.starttourPanel.Title = 'start tour';
            app.saveButton = uibutton(app.starttourPanel, 'push');
            app.saveButton.Text = 'save';
            app.confirmButton = uibutton(app.starttourPanel, 'push');
            app.confirmButton.Text = 'confirm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end